classdef edititemapp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        IsBoughtCheckBox             matlab.ui.control.CheckBox
        PriorityDropDown             matlab.ui.control.DropDown
        PriorityDropDownLabel        matlab.ui.control.Label
        UnitDropDown                 matlab.ui.control.DropDown
        UnitDropDownLabel            matlab.ui.control.Label
        DeleteButton                 matlab.ui.control.Button
        SaveButton                   matlab.ui.control.Button
        AmountEditField              matlab.ui.control.NumericEditField
        AmountEditFieldLabel         matlab.ui.control.Label
        NameofproductEditField       matlab.ui.control.EditField
        NameofproductEditFieldLabel  matlab.ui.control.Label
        BackButton                   matlab.ui.control.Button
    end

    
    properties (Access = private)
        mainapp % Description
        nameOfProduct
        amount
        unit
        priority
        isBought
        id

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp, datafrommainapp)
            app.id = datafrommainapp;
            dbfile = fullfile(".\","Groceries.db");
            conn = sqlite(dbfile);
            
            sqlquery = strcat('SELECT * FROM ShoppingList WHERE ID= ', num2str(app.id),';');
            results = fetch(conn,sqlquery);
            app.NameofproductEditField.Value = table2array(results(1, 2));
            app.AmountEditField.Value = double(table2array(results(1, 3)));
            app.UnitDropDown.Value = table2array(results(1, 4));
            app.PriorityDropDown.Value = app.PriorityDropDown.Items(double(table2array(results(1, 5))));
            app.IsBoughtCheckBox.Value = table2array(results(1, 6));

            app.nameOfProduct = table2array(results(1, 2));
            app.amount = double(table2array(results(1, 3)));
            app.unit = table2array(results(1, 4));
            app.priority = double(table2array(results(1, 5)));
            app.isBought = table2array(results(1, 6));

        end

        % Button pushed function: BackButton
        function BackButtonPushed(app, event)
            app.mainapp=mainapp();
            app.delete
        end

        % Value changed function: AmountEditField
        function AmountEditFieldValueChanged(app, event)
            app.amount = app.AmountEditField.Value;
            
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            dbfile = fullfile(".\","Groceries.db");
            conn = sqlite(dbfile);
            
            sqlquery = strcat('UPDATE ShoppingList SET NameOfProduct="', app.nameOfProduct, '",Amount=', num2str(app.amount), ',Unit="', app.unit,'",Priority=', num2str(app.priority),',IsBought="', num2str(app.isBought), '" WHERE ID=',num2str(app.id),';');
            execute(conn,sqlquery);
        end

        % Button pushed function: DeleteButton
        function DeleteButtonPushed(app, event)
            dbfile = fullfile(".\","Groceries.db");
            conn = sqlite(dbfile);

            fig = uifigure;

            msg = 'Do you really want to delete item ' + app.nameOfProduct + "?";
            title = 'Confirm Delete';
            selection = uiconfirm(fig,msg,title, ...
                'Options',{'Delete','Cancel'}, ...
                'DefaultOption',2,'CancelOption',2);
            close(fig)
            if selection == "Delete"
                sqlquery = strcat('DELETE FROM ShoppingList WHERE ID =', num2str(app.id),';');
                execute(conn,sqlquery);
                app.mainapp=mainapp();
                app.delete
            end
        end

        % Value changed function: UnitDropDown
        function UnitDropDownValueChanged(app, event)
            app.unit = app.UnitDropDown.Value;
            
        end

        % Value changed function: PriorityDropDown
        function PriorityDropDownValueChanged(app, event)
            app.priority = app.PriorityDropDown.Value;
            
        end

        % Value changed function: IsBoughtCheckBox
        function IsBoughtCheckBoxValueChanged(app, event)
            app.isBought = app.IsBoughtCheckBox.Value;
            
        end

        % Value changing function: NameofproductEditField
        function NameofproductEditFieldValueChanging(app, event)
            app.nameOfProduct = event.Value;
            if(app.nameOfProduct ~= "")
                app.SaveButton.Enable = 'on';
            else
                app.SaveButton.Enable = 'off';
            end
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.BackButton = uibutton(app.UIFigure, 'push');
            app.BackButton.ButtonPushedFcn = createCallbackFcn(app, @BackButtonPushed, true);
            app.BackButton.Text = 'Back';
            app.NameofproductEditFieldLabel = uilabel(app.UIFigure);
            app.NameofproductEditFieldLabel.Text = 'Name of product';
            app.NameofproductEditField = uieditfield(app.UIFigure, 'text');
            app.NameofproductEditField.ValueChangingFcn = createCallbackFcn(app, @NameofproductEditFieldValueChanging, true);
            app.AmountEditFieldLabel = uilabel(app.UIFigure);
            app.AmountEditFieldLabel.Text = 'Amount';
            app.AmountEditField = uieditfield(app.UIFigure, 'numeric');
            app.AmountEditField.Limits = [0 Inf];
            app.AmountEditField.ValueChangedFcn = createCallbackFcn(app, @AmountEditFieldValueChanged, true);
            app.SaveButton = uibutton(app.UIFigure, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = 'Save';
            app.DeleteButton = uibutton(app.UIFigure, 'push');
            app.DeleteButton.ButtonPushedFcn = createCallbackFcn(app, @DeleteButtonPushed, true);
            app.DeleteButton.Text = 'Delete';
            app.UnitDropDownLabel = uilabel(app.UIFigure);
            app.UnitDropDownLabel.Text = 'Unit';
            app.UnitDropDown = uidropdown(app.UIFigure);
            app.UnitDropDown.Items = {'pcs', 'ml', 'kg', 'g'};
            app.UnitDropDown.ValueChangedFcn = createCallbackFcn(app, @UnitDropDownValueChanged, true);
            app.UnitDropDown.Value = 'pcs';
            app.PriorityDropDownLabel = uilabel(app.UIFigure);
            app.PriorityDropDownLabel.Text = 'Priority';
            app.PriorityDropDown = uidropdown(app.UIFigure);
            app.PriorityDropDown.Items = {'1', '2', '3', '4', '5'};
            app.PriorityDropDown.ValueChangedFcn = createCallbackFcn(app, @PriorityDropDownValueChanged, true);
            app.PriorityDropDown.Value = '1';
            app.IsBoughtCheckBox = uicheckbox(app.UIFigure);
            app.IsBoughtCheckBox.ValueChangedFcn = createCallbackFcn(app, @IsBoughtCheckBoxValueChanged, true);
            app.IsBoughtCheckBox.Text = 'Is Bought';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = edititemapp(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end