classdef newitemapp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        PriorityDropDown             matlab.ui.control.DropDown
        PriorityDropDownLabel        matlab.ui.control.Label
        UnitDropDown                 matlab.ui.control.DropDown
        UnitDropDownLabel            matlab.ui.control.Label
        AddProductButton             matlab.ui.control.Button
        AmountEditField              matlab.ui.control.NumericEditField
        AmountEditFieldLabel         matlab.ui.control.Label
        NameOfProductEditField       matlab.ui.control.EditField
        NameOfProductEditFieldLabel  matlab.ui.control.Label
        BackButton                   matlab.ui.control.Button
    end

    
    properties (Access = private)
        mainapp % Description
        nameOfProduct
        amount
        unit
        priority
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp)
            app.nameOfProduct = "";
            app.amount = 1;
            app.unit = "pcs";
            app.priority = 1;
        end

        % Button pushed function: BackButton
        function BackButtonPushed(app, event)
            app.mainapp=mainapp()
            app.delete
        end

        % Value changed function: AmountEditField
        function AmountEditFieldValueChanged(app, event)
            app.amount = app.AmountEditField.Value;

        end

        % Button pushed function: AddProductButton
        function AddProductButtonPushed(app, event)

            dbfile = fullfile("C:\Users\smutn\OneDrive\Plocha\matlab GUI\MATLAB-and-SQLite","Groceries.db");
            conn = sqlite(dbfile);
            num2str(app.priority)
            sqlquery = strcat('INSERT INTO ShoppingList (NameOfProduct,Amount,Unit,Priority,IsBought) VALUES ( "', app.nameOfProduct, '", ',num2str(app.amount), ', "', app.unit, '", ', num2str(app.priority), ', ', '"0"', ');')
            execute(conn,sqlquery)
            
            app.mainapp=mainapp();
            app.delete
        end

        % Value changing function: NameOfProductEditField
        function NameOfProductEditFieldValueChanging(app, event)
            app.nameOfProduct = event.Value;
            if(app.nameOfProduct ~= "")
                app.AddProductButton.Enable = 'on';
            else
                app.AddProductButton.Enable = 'off';
            end


        end

        % Value changed function: UnitDropDown
        function UnitDropDownValueChanged(app, event)
            app.unit = app.UnitDropDown.Value;
            
        end

        % Value changed function: PriorityDropDown
        function PriorityDropDownValueChanged(app, event)
            app.priority = app.PriorityDropDown.Value;
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.BackButton = uibutton(app.UIFigure, 'push');
            app.BackButton.ButtonPushedFcn = createCallbackFcn(app, @BackButtonPushed, true);
            app.BackButton.Text = 'Back';
            app.NameOfProductEditFieldLabel = uilabel(app.UIFigure);
            app.NameOfProductEditFieldLabel.Text = 'Name Of Product';
            app.NameOfProductEditField = uieditfield(app.UIFigure, 'text');
            app.NameOfProductEditField.ValueChangingFcn = createCallbackFcn(app, @NameOfProductEditFieldValueChanging, true);
            app.NameOfProductEditField.Placeholder = 'product''s name';
            app.AmountEditFieldLabel = uilabel(app.UIFigure);
            app.AmountEditFieldLabel.Text = 'Amount';
            app.AmountEditField = uieditfield(app.UIFigure, 'numeric');
            app.AmountEditField.Limits = [1 Inf];
            app.AmountEditField.ValueChangedFcn = createCallbackFcn(app, @AmountEditFieldValueChanged, true);
            app.AmountEditField.Value = 1;
            app.AddProductButton = uibutton(app.UIFigure, 'push');
            app.AddProductButton.ButtonPushedFcn = createCallbackFcn(app, @AddProductButtonPushed, true);
            app.AddProductButton.Enable = 'off';
            app.AddProductButton.Text = 'Add Product';
            app.UnitDropDownLabel = uilabel(app.UIFigure);
            app.UnitDropDownLabel.Text = 'Unit';
            app.UnitDropDown = uidropdown(app.UIFigure);
            app.UnitDropDown.Items = {'pcs', 'ml', 'kg', 'g'};
            app.UnitDropDown.ValueChangedFcn = createCallbackFcn(app, @UnitDropDownValueChanged, true);
            app.UnitDropDown.Value = 'pcs';
            app.PriorityDropDownLabel = uilabel(app.UIFigure);
            app.PriorityDropDownLabel.Text = 'Priority';
            app.PriorityDropDown = uidropdown(app.UIFigure);
            app.PriorityDropDown.Items = {'1', '2', '3', '4', '5'};
            app.PriorityDropDown.ValueChangedFcn = createCallbackFcn(app, @PriorityDropDownValueChanged, true);
            app.PriorityDropDown.Value = '1';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = newitemapp(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end